classdef Model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModellingUIFigure               matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TransmissionrateEditFieldLabel  matlab.ui.control.Label
        TransmissionrateEditField       matlab.ui.control.NumericEditField
        IncubationrateEditFieldLabel    matlab.ui.control.Label
        IncubationrateEditField         matlab.ui.control.NumericEditField
        IsolationrateEditFieldLabel     matlab.ui.control.Label
        IsolationrateEditField          matlab.ui.control.NumericEditField
        PlotButton                      matlab.ui.control.Button
        RecoveryrateLabel               matlab.ui.control.Label
        RecoveryrateEditField           matlab.ui.control.NumericEditField
        LostimmunityrateEditFieldLabel  matlab.ui.control.Label
        LostimmunityrateEditField       matlab.ui.control.NumericEditField
        FatalityrateEditFieldLabel      matlab.ui.control.Label
        FatalityrateEditField           matlab.ui.control.NumericEditField
        ParametersLabel                 matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        ModelUIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ModellingUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {417, 417};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {263, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
                       
           beta = app.TransmissionrateEditField.Value;                % transmission rate     
           gamma = app.RecoveryrateEditField.Value;                   % recovery rate 
           lambda = app.IncubationrateEditField.Value;                % incubation rate
           omega = app.LostimmunityrateEditField.Value;               % lost immunity rate
           q = app.IsolationrateEditField.Value;                      % quarantine rate
           dr = app.FatalityrateEditField.Value;                      % dead rate
           N = 1000;                     % population size
           delta_t = 0.0001;             % time step
           t_end = 30;                   % time duration
           t_steps = t_end/delta_t;      % total time step
           y0 = [950 0 50 0 0 0]';       % Initial condition [S0 E0 I0 Q0 R0]
           runs = 10;                    % number of runs
           time = 0:delta_t:t_end;       % time vector

           S = zeros(runs,t_steps+1);       % an array of S
           E = zeros(runs,t_steps+1);       % an array of E
           I = zeros(runs,t_steps+1);       % an array of I
           Q = zeros(runs,t_steps+1);       % an array of Q
           R = zeros(runs,t_steps+1);       % an array of R
           D = zeros(runs,t_steps+1);       % an array of D

           S(:,1) = y0(1);        % Inital number of S
           E(:,1) = y0(2);        % Inital number of E
           I(:,1) = y0(3);        % Inital number of I
           Q(:,1) = y0(4);        % Inital number of Q
           R(:,1) = y0(5);        % Inital number of R
           D(:,1) = y0(6);        % Inital number of D

           for k = 1:runs
           for t = 1:t_steps
               p1 = beta*(I(k,t)+E(k,t))*S(k,t)/N*delta_t;   % probability of a new exposed
               p2 = lambda*E(k,t)*delta_t;                   % probability of a new infection
               p3 = q*I(k,t)*delta_t;                        % probability of a new quarantined
               p4 = gamma*I(k,t)*delta_t;                    % probability of a new recovery from I
               p5 = gamma*Q(k,t)*delta_t;                    % probability of a new recovery from Q
               p6 = omega*R(k,t)*delta_t;                    % probability of a new susceptible 
               p7 = dr*I(k,t)*delta_t;                    % probability of a new dead from I
               p8 = dr*Q(k,t)*delta_t;                    % probability of a new dead from Q
    
               r = rand;                          % random number from a uniform distribution of (0,1)
    
               % conditions for a new exposed
               if (r > 0) && (r <= p1)
                   S(k,t+1) = S(k,t)-1;
                   E(k,t+1) = E(k,t)+1;
                   I(k,t+1) = I(k,t);
                   Q(k,t+1) = Q(k,t);
                   R(k,t+1) = R(k,t);
                   D(k,t+1) = D(k,t);
        
               % conditions for a new infection
               elseif (r > p1) && (r <= p1+p2)
                   S(k,t+1) = S(k,t);
                   E(k,t+1) = E(k,t)-1;
                   I(k,t+1) = I(k,t)+1;
                   Q(k,t+1) = Q(k,t);
                   R(k,t+1) = R(k,t);
                   D(k,t+1) = D(k,t);
    
               % conditions for a new quarantined
               elseif (r > p1+p2) && (r <= p1+p2+p3)
                   S(k,t+1) = S(k,t);
                   E(k,t+1) = E(k,t);
                   I(k,t+1) = I(k,t)-1;
                   Q(k,t+1) = Q(k,t)+1;
                   R(k,t+1) = R(k,t);
                   D(k,t+1) = D(k,t);
               
               % conditions for a new recovery from I
               elseif (r > p1+p2+p3) && (r <= p1+p2+p3+p4)
               	   S(k,t+1) = S(k,t);
               	   E(k,t+1) = E(k,t);
                   I(k,t+1) = I(k,t)-1;
                   Q(k,t+1) = Q(k,t);
                   R(k,t+1) = R(k,t)+1;
                   D(k,t+1) = D(k,t);
        
               % conditions for a new recovery from Q
               elseif (r > p1+p2+p3+p4) && (r <= p1+p2+p3+p4+p5)
                   S(k,t+1) = S(k,t);
               	   E(k,t+1) = E(k,t);
                   I(k,t+1) = I(k,t);
                   Q(k,t+1) = Q(k,t)-1;
                   R(k,t+1) = R(k,t)+1;
                   D(k,t+1) = D(k,t);
        
               % conditions for a new susceptible
               elseif (r > p1+p2+p3+p4+p5) && (r <= p1+p2+p3+p4+p5+p6)
    	           S(k,t+1) = S(k,t)+1;
    	           E(k,t+1) = E(k,t);
                   I(k,t+1) = I(k,t);
                   Q(k,t+1) = Q(k,t);
                   R(k,t+1) = R(k,t)-1;
                   D(k,t+1) = D(k,t);
        
               % conditions for a new dead from I
               elseif (r > p1+p2+p3+p4+p5+p6) && (r <= p1+p2+p3+p4+p5+p6+p7)
    	           S(k,t+1) = S(k,t);
    	           E(k,t+1) = E(k,t);
                   I(k,t+1) = I(k,t)-1;
                   Q(k,t+1) = Q(k,t);
                   R(k,t+1) = R(k,t);
                   D(k,t+1) = D(k,t)+1;
        
               % conditions for a new dead from Q
               elseif (r > p1+p2+p3+p4+p5+p6+p7) && (r <= p1+p2+p3+p4+p5+p6+p7+p8)
    	           S(k,t+1) = S(k,t);
    	           E(k,t+1) = E(k,t);
                   I(k,t+1) = I(k,t);
                   Q(k,t+1) = Q(k,t)-1;
                   R(k,t+1) = R(k,t);
                   D(k,t+1) = D(k,t)+1;
        
               % conditions for no change
               elseif (r > p1+p2+p3+p4+p5+p6+p7+p8) && (r < 1)
    	           S(k,t+1) = S(k,t);
    	           E(k,t+1) = E(k,t);
                   I(k,t+1) = I(k,t);
                   Q(k,t+1) = Q(k,t);
                   R(k,t+1) = R(k,t);
                   D(k,t+1) = D(k,t);
               end
           end

           % stochastic simulation 
           plot(app.ModelUIAxes,time,S(k,:),'Color','#0071BC')
           hold (app.ModelUIAxes,'on')
           plot(app.ModelUIAxes,time,E(k,:),'Color','#D85218')
           plot(app.ModelUIAxes,time,I(k,:),'Color','#ECB01F')
           plot(app.ModelUIAxes,time,Q(k,:),'Color','#7D2E8D')
           plot(app.ModelUIAxes,time,R(k,:),'Color','#76AB2F')
           plot(app.ModelUIAxes,time,D(k,:),'Color','#4CBDED')
           legend(app.ModelUIAxes,'S(t)','E(t)','I(t)','Q(t)','R(t)','D(t)')

           end

           hold (app.ModelUIAxes,'off')
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModellingUIFigure = uifigure('Visible', 'off');
            app.ModellingUIFigure.Name = 'Mortgage Calculator';
            app.ModellingUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ModellingUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TransmissionrateEditFieldLabel = uilabel(app.LeftPanel);
            app.TransmissionrateEditFieldLabel.Text = 'Transmission rate';
            app.TransmissionrateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TransmissionrateEditField.Limits = [0 Inf];
            app.IncubationrateEditFieldLabel = uilabel(app.LeftPanel);
            app.IncubationrateEditFieldLabel.Text = 'Incubation rate';
            app.IncubationrateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IncubationrateEditField.Limits = [0 Inf];
            app.IsolationrateEditFieldLabel = uilabel(app.LeftPanel);
            app.IsolationrateEditFieldLabel.Text = 'Isolation rate';
            app.IsolationrateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IsolationrateEditField.Limits = [0 Inf];
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RecoveryrateLabel = uilabel(app.LeftPanel);
            app.RecoveryrateLabel.Text = 'Recovery rate';
            app.RecoveryrateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RecoveryrateEditField.Limits = [0 Inf];
            app.LostimmunityrateEditFieldLabel = uilabel(app.LeftPanel);
            app.LostimmunityrateEditFieldLabel.Text = 'Lost immunity rate';
            app.LostimmunityrateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LostimmunityrateEditField.Limits = [0 Inf];
            app.FatalityrateEditFieldLabel = uilabel(app.LeftPanel);
            app.FatalityrateEditFieldLabel.Text = 'Fatality rate';
            app.FatalityrateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FatalityrateEditField.Limits = [0 Inf];
            app.ParametersLabel = uilabel(app.LeftPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.RightPanel = uipanel(app.GridLayout);
            app.ModelUIAxes = uiaxes(app.RightPanel);
            title(app.ModelUIAxes, 'Spread of COVID-19')
            xlabel(app.ModelUIAxes, 'Number of days')
            ylabel(app.ModelUIAxes, 'Population size')
            % Show the figure after all components are created
            app.ModellingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModellingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModellingUIFigure)
        end
    end
end